classdef tongueTipVisualizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SelectfilesTab                 matlab.ui.container.Tab
        SelectrootdirectoryButton      matlab.ui.control.Button
        RootdirectoryEditFieldLabel    matlab.ui.control.Label
        RootdirectoryEditField         matlab.ui.control.EditField
        recursiveSearchCheckBox        matlab.ui.control.CheckBox
        t_statsfilesfoundLabel         matlab.ui.control.Label
        filesFoundListBox              matlab.ui.control.ListBox
        t_statsfilesselectedListBoxLabel  matlab.ui.control.Label
        filesSelectedListBox           matlab.ui.control.ListBox
        addTStats                      matlab.ui.control.Button
        removeTStats                   matlab.ui.control.Button
        moveTStatsUp                   matlab.ui.control.Button
        moveTStatsDown                 matlab.ui.control.Button
        rootDirectoryStatusLabel       matlab.ui.control.Label
        removeAllTStats                matlab.ui.control.Button
        addAllTStats                   matlab.ui.control.Button
        Loadt_statsButton              matlab.ui.control.Button
        FilterregexLabel               matlab.ui.control.Label
        tstatsFilterRegexEditField     matlab.ui.control.EditField
        VisualizeTab                   matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        VariableExplorerTab            matlab.ui.container.Tab
        SearchfieldsEditFieldLabel     matlab.ui.control.Label
        SearchfieldsEditField          matlab.ui.control.EditField
        FieldsListBoxLabel             matlab.ui.control.Label
        FieldsListBox                  matlab.ui.control.ListBox
        FielddetailsTextAreaLabel      matlab.ui.control.Label
        FielddetailsTextArea           matlab.ui.control.TextArea
        ViewthisvariableButton         matlab.ui.control.Button
        ViewallvariablesButton         matlab.ui.control.Button
        ConveniencefunctionsTextAreaLabel  matlab.ui.control.Label
        ConveniencefunctionsTextArea   matlab.ui.control.TextArea
        SavesTab                       matlab.ui.container.Tab
        SavefileEditFieldLabel         matlab.ui.control.Label
        SavefileEditField              matlab.ui.control.EditField
        SavedvisualizationsListBoxLabel  matlab.ui.control.Label
        SavedvisualizationsListBox     matlab.ui.control.ListBox
        LoadvisualizationsfromfileButton  matlab.ui.control.Button
        SetupselectedvisualizationButton  matlab.ui.control.Button
        SaveloadedvisualizationstofileButton  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ExportcurrentvisualizationButton  matlab.ui.control.Button
        NameforexportEditFieldLabel    matlab.ui.control.Label
        NameforexportEditField         matlab.ui.control.EditField
        SelectedvisualizationTextAreaLabel  matlab.ui.control.Label
        SelectedvisualizationTextArea  matlab.ui.control.TextArea
        BrowseSavedVisualizationsButton  matlab.ui.control.Button
        DeleteselectedButton           matlab.ui.control.Button
        HelpTab                        matlab.ui.container.Tab
        HelpTextAreaLabel              matlab.ui.control.Label
        HelpTextArea                   matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        bankControls            BankControl
        maxPathDisplayLength    double
        t_stats                 table
        fieldNameDefinitions    struct
        progressDialog          matlab.ui.dialog.ProgressDialog
        axesContextMenu         matlab.ui.container.ContextMenu
        loadedBanks             struct
        waitToUpdate            logical
    end
    
    methods (Access = private)
               
        function populateTStats(app)
            root = app.RootdirectoryEditField.Value;
            if isempty(root)
                root = '.';
            end
            app.filesFoundListBox.Items = {};
            app.filesFoundListBox.ItemsData = {};
            if ~exist(root, 'dir')
                app.rootDirectoryStatusLabel.Text = 'Directory does not exist.';
            else
                app.rootDirectoryStatusLabel.Text = '';
                recurse = app.recursiveSearchCheckBox.Value;
                % Find all t_stats files
                pathsFound = findFilesByRegex(root, app.tstatsFilterRegexEditField.Value, false, recurse, false, true);
                % Add t_stats files that are not already selected to the
                % "found" box
                selectedPaths = app.filesSelectedListBox.ItemsData;
                if isempty(selectedPaths)
                    selectedPaths = {};
                end
                if isempty(pathsFound)
                    uialert(app.UIFigure, 'No files found. Check that the files are in that directory, and that the regex filter is correct.', 'No matching files found');
                    pathsFound = {};
                end
                unselectedPaths = setdiff(pathsFound, selectedPaths);
                app.filesFoundListBox.ItemsData = unselectedPaths;                
            end
            app.updateFileListBoxDisplays()
        end
        
        function updateFileListBoxDisplays(app)
            % Update abbreviated display names for file list boxes based on
            %   underlying full paths in ItemsData attribute
            if isempty(app.filesSelectedListBox.ItemsData)
                app.filesSelectedListBox.ItemsData = {};
            end
            if isempty(app.filesFoundListBox.ItemsData)
                app.filesFoundListBox.ItemsData = {};
            end
            app.filesSelectedListBox.Items = cellfun(@(x)abbreviateText(x, app.maxPathDisplayLength, 0.2), app.filesSelectedListBox.ItemsData, 'UniformOutput', false);
            app.filesFoundListBox.Items = cellfun(@(x)abbreviateText(x, app.maxPathDisplayLength, 0.2),    app.filesFoundListBox.ItemsData, 'UniformOutput', false);
        end
        
        function updatePlots(app, bankIndices, rows, cols)
            if isempty(app.t_stats) || app.waitToUpdate
                return;
            end
            if ~exist('bankIndices', 'var') || isnan(bankIndices)
                bankIndices = 1:length(app.bankControls);
            end
            allRows = ~exist('rows', 'var') || isnan(rows);
            allCols = ~exist('cols', 'var') || isnan(cols);
            
            app.startProgressDialog('Updating plots...', 'Please wait');
            clockSeed = sum(clock()*1000);
            try
                disp('Updating plots')
                % Rows is an optional array of row indices indicating which
                %   rows to update.
                % Cols is an optional array of column indices indicating which
                %   columns to update.
                for bankIndex = bankIndices
                    bank = app.bankControls(bankIndex);
                    [nRows, nCols] = bank.getBankSize();
                    if allRows
                        rows = 1:nRows;
                    end
                    if allCols
                        cols = 1:nCols;
                    end

                    prePlotCommands = app.bankControls(bankIndex).getPrePlotCommandText();

                    % Assign all fields to individual array variables
                    forbiddenFieldNames = {'app', 'col', 'row', 'j'};
                    fieldNames = app.t_stats.Properties.VariableNames;
    
                    count = 0;
                    % Get the field names that are currently in use in variables
                    allPlotExpressions = bank.getAllVariableText();
                    fieldNamesFiltered = fieldNames(cellfun(@(pattern)contains(allPlotExpressions, pattern), fieldNames));
                    % Loop through plots and update them
                    for col = cols
                        otherFilterVars = struct();
                        otherFilterVars.col = col;
                        assignin('caller', 'col', col)
                        
                        Tfiltereds = bank.filterControls(col).filterTable(app.t_stats, otherFilterVars);
    
                        numFilters = length(Tfiltereds);
                        for filterNum = 1:numFilters
                            Tfiltered = Tfiltereds{filterNum};
        %                    disp(['Filter took us from ', num2str(height(app.t_stats)), ' rows to ', num2str(height(Tfiltered)), ' rows '])
                            for j = 1:length(fieldNamesFiltered)
                                fieldName = fieldNamesFiltered{j};
                                % Make sure we don't overwrite necessary variables
                                if ~any(strcmp(fieldName, forbiddenFieldNames))
                                    fieldDataFiltered = Tfiltered.(fieldName);
                                    assignin('caller', fieldName, fieldDataFiltered);
                                else
                                    error(['Using field name ', fieldName, ' it is forbidden for namespace collision reasons.'])
                                end
                            end
                            for row = rows
                                count = count + 1/numFilters;
                                ax = bank.getAxes(row, col);
                                variableTexts    = bank.getCleanedVariableText(row);
                                if isempty(variableTexts)
                                    ax.cla();
                                    continue;
                                end
                                assignin('caller', 'row', row)
                                % Store all N variables in a cell array
                                for L = 1:length(variableTexts)
                                    % Execute pre-plot commands
                                    for ppc = 1:length(prePlotCommands)
                                        evalin('caller', prePlotCommands{ppc});
                                    end
                                    variableText = variableTexts{L};
                                    variablesToPlot = evalin('caller', ['{', variableText, '}']);
                                    maxLength = max(cellfun(@length, variablesToPlot));
                                    if maxLength > 100 && length(variablesToPlot) > 1
                                        selection = uiconfirm(app.UIFigure, ...
                                            ['The chosen filter and variable settings will result in at least ', num2str(maxLength), ' plots - that could take a while. Do you want to proceed?'], ...
                                            'That''s a lotta plots!',...
                                            'Options', {'Proceed', 'Cancel'},...
                                            'Icon', 'warning');
                                        if strcmp(selection, 'Cancel')
                                            continue;
                                        end
                                    end
%                                    variableNames = 'none';
                                    variableNames = evalin('caller', ['escapeLatex(getVarNames(', variableText, '))']);
                                    ax.XLimMode = 'auto';
                                    ax.YLimMode = 'auto';
                                    ax.ZLimMode = 'auto';
                                    clearFirst = (filterNum==1 && L==1);
                                    plotArbitraryData(ax, variablesToPlot, variableNames, clearFirst);
                                end
                                app.progressDialog.Value = count / (length(rows)*length(cols));
                            end
                        end
                    end
                    
    %                for row=rows
    %                    app.alignAxisLimits(row)
    %                end
                end
            catch ME
                disp(getReport(ME))
                close(app.progressDialog);
            end
            close(app.progressDialog);
        end
                
        function alignAxisLimits(app, row)
            rowXLim = cell2mat(arrayfun(@(a)a.XLim, app.bankGrid(:, row), 'UniformOutput', false));
            rowYLim = cell2mat(arrayfun(@(a)a.YLim, app.bankGrid(:, row), 'UniformOutput', false));
            rowZLim = cell2mat(arrayfun(@(a)a.ZLim, app.bankGrid(:, row), 'UniformOutput', false));
            jointXLim = [min(rowXLim(:, 1)), max(rowXLim(:, 2))];
            jointYLim = [min(rowYLim(:, 1)), max(rowYLim(:, 2))];
            jointZLim = [min(rowZLim(:, 1)), max(rowZLim(:, 2))];
            [nCols, ~] = size(app.bankGrid);
            for col = 1:nCols
%                app.bankGrid(col, row).XLim = jointXLim;
%                app.bankGrid(col, row).YLim = jointYLim;
%                app.bankGrid(col, row).ZLim = jointZLim;
            end
        end
        
        function loadFieldNameDefinitions(app)
            [path, ~, ~] = fileparts(mfilename('fullpath'));
            tsfn = findFilesByRegex(path, 't_stats_field_names.csv');
%            fieldNameFile = fullfile(path, 't_stats_field_names.csv');
            if isempty(tsfn)
                error('Warning, could not find field name definitions file.');
            end
            fieldNameFile = tsfn{1};
            fieldNameCell = readmatrix(fieldNameFile, 'NumHeaderLines', 0, 'OutputType', 'char');
            app.fieldNameDefinitions = cell2struct(fieldNameCell(:, 2), fieldNameCell(:, 1), 1);
            app.populateFieldNameDefinitionsListBox('');
        end
        
        function populateFieldNameDefinitionsListBox(app, filterTerm)
            if ~exist('filterTerm', 'var')
                filterTerm = app.SearchfieldsEditField.Value;
            end
            fieldNamesFromDefinitions = fieldnames(app.fieldNameDefinitions)';
            if ~isempty(app.t_stats)
                fieldNamesFromTStats = app.t_stats.Properties.VariableNames;
            else
                fieldNamesFromTStats = {};
            end
            extraFieldNamesFromTStats = fieldNamesFromTStats(cellfun(@(fnfts)~any(strcmp(fnfts, fieldNamesFromDefinitions)), fieldNamesFromTStats));
            fieldNames = [fieldNamesFromDefinitions, extraFieldNamesFromTStats];
            filteredIdx = cellfun(@(fn)contains(fn, filterTerm) || (isfield(app.fieldNameDefinitions, fn) && contains(app.fieldNameDefinitions.(fn), filterTerm)), fieldNames);
            fieldNamesFiltered = fieldNames(filteredIdx);
%            fieldsTextFiltered = cellfun(@(fn)[fn, ': ', app.fieldNameDefinitions.(fn)], fieldNamesFiltered, 'UniformOutput',false);
%            app.FieldsListBox.Items = fieldsTextFiltered;
            app.FieldsListBox.Items = fieldNamesFiltered;
            app.FieldsListBox.ItemsData = fieldNamesFiltered;
            if isempty(app.FieldsListBox.ItemsData) || isempty(app.FieldsListBox.Value) || ~any(strcmp(app.FieldsListBox.ItemsData, app.FieldsListBox.Value))
                app.FieldsListBox.Value = {};
            else
                app.updateFieldDetails(app.FieldsListBox.Value);
            end
        end
        
        function updateFieldDetails(app, fieldName)
            if istable(app.t_stats) && ismember(fieldName, app.t_stats.Properties.VariableNames)
                values = app.t_stats.(fieldName);
                valueInfo = vinfo(values, 10, 10, 0, fieldName);
            else
                valueInfo = '(This field is not included in the currently loaded t_stats files)';
            end
            
            if isfield(app.fieldNameDefinitions, fieldName)
                fieldDetail = {[fieldName, ':'], ...
                        app.fieldNameDefinitions.(fieldName), ...
                        '', ...
                        valueInfo ...
                    };
            else
                fieldDetail = {[fieldName, ':'], ...
                        'No field definition available', ...
                        '', ...
                        valueInfo};
            end
            app.FielddetailsTextArea.Value = fieldDetail;
        end
        
        function startProgressDialog(app, title, message)
             app.progressDialog = uiprogressdlg(app.UIFigure, 'Title',title, 'Message',message);
        end
        
        function createAxesContextMenu(app)
            app.axesContextMenu = uicontextmenu(app.UIFigure);
            menuAdd = uimenu(app.axesContextMenu, 'Text', 'Add...');
            menuRemove = uimenu(app.axesContextMenu, 'Text', 'Remove...');
            m1 = uimenu(menuAdd,    'Text', 'Row left',     'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, -1,  'Row'});
            m2 = uimenu(menuAdd,    'Text', 'Row right',    'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, 1, 'Row'});
            m3 = uimenu(menuAdd,    'Text', 'Column above', 'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, -1, 'Column'});
            m4 = uimenu(menuAdd,    'Text', 'Column below', 'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, 1, 'Column'});
            m5 = uimenu(menuRemove, 'Text', 'Row',          'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, 0, 'Row'});
            m6 = uimenu(menuRemove, 'Text', 'Column',       'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, 0, 'Column'});
            m7 = uimenu(menuRemove, 'Text', 'Entire bank',  'MenuSelectedFcn',{@app.alterGridLayoutCallback, 1, 0, 'Bank'});
        end
        
        function alterGridLayoutCallback(app, src, event, addRemove, direction, type)
            disp('alterGridLayoutCallback:')
            disp(addRemove)
            disp(direction)
            disp(type)
            disp(src)
            disp(src.UserData)
            disp(event)
        end
        
        function bankSizeChangedHandler(app, src, event)
            app.updateBankLayout();
            app.updatePlots();
        end
        
        function updateBankLayout(app)
            numBanks = length(app.bankControls);
            newColumnWidths = cell([1, numBanks]);
            for k = 1:numBanks
                bankWidth = app.bankControls(k).getBankPixelWidth();
                newColumnWidths{k} = bankWidth;
            end
            app.GridLayout.ColumnWidth = newColumnWidths;
            app.GridLayout.RowHeight = {'1x'};
        end
        
        function numBanks = getNumBanks(app)
            numBanks = length(app.bankControls);
        end

        function addRemoveBankHandler(app, src, event)
            bankIndex = find(src == app.bankControls, 1);
            switch event.EventName
                case 'AddBefore'
                    app.addBank(bankIndex);
                case 'AddAfter'
                    app.addBank(bankIndex+1);
                case 'Remove'
                    app.removeBank(bankIndex);
            end
        end
        
        function addBank(app, addIndex, bankStruct)
            numBanks = app.getNumBanks();
            if ~exist('addIndex', 'var') || isnan(addIndex)
                addIndex = numBanks+1;
            end
            if ~exist('bankStruct', 'var')
                bankStruct = struct.empty();
            end
            % Update bank positions
            for k = addIndex:numBanks
                app.bankControls(k).updatePosition(1, k+1);
            end
            newBank = app.createNewBank(1, addIndex, bankStruct);
            app.bankControls = [app.bankControls(1:addIndex-1), newBank, app.bankControls(addIndex:numBanks)];
            app.updateBankLayout();
        end
        
        function removeBank(app, removeIndex)
            numBanks = app.getNumBanks();
            if isnan(removeIndex)
                removeIndex = numBanks;
            end
            % Update bank positions
            for k = removeIndex+1:numBanks
                app.bankControls(k).updatePosition(1, k-1);
            end
            delete(app.bankControls(removeIndex));
            app.bankControls(removeIndex) = [];
            app.updateBankLayout();
        end
        
        function removeAllBanks(app)
            numBanks = app.getNumBanks();
            for k = 1:numBanks
                app.removeBank(NaN);
            end
        end
        
        function newBank = createNewBank(app, row, col, varargin)
            if isempty(varargin) || isempty(varargin{1})
                bankStruct = struct.empty();
                nFilterControls = 2;
                nVariableControls = 3;
            elseif length(varargin) == 1
                % Extra arg is a bankStruct to load
                bankStruct = varargin{1};
                nFilterControls = 0;
                nVariableControls = 0;
            elseif length(varargin) == 2
                % Extr args are the number of filters and variables
                bankStruct = struct.empty();
                nFilterControls = varargin{1};
                nVariableControls = varargin{2};
            else
                error('Incorrect # of args');
            end

            newBank = BankControl(app.GridLayout, row, col, nVariableControls, nFilterControls);
            addlistener(newBank, 'VariableChanged', @app.variableChangedHandler);
            addlistener(newBank, 'FilterChanged', @app.filterChangedHandler);
            addlistener(newBank, 'BankSizeChanged', @app.bankSizeChangedHandler);
            addlistener(newBank, 'AddBefore', @app.addRemoveBankHandler);
            addlistener(newBank, 'AddAfter', @app.addRemoveBankHandler);
            addlistener(newBank, 'Remove', @app.addRemoveBankHandler);
            addlistener(newBank, 'ExportAll', @app.exportAllBanksToFigure);
            addlistener(newBank, 'PrePlotCommandsChanged', @app.prePlotCommandsChangedHandler);
            if ~isempty(bankStruct)
                newBank.importBank(bankStruct);
            end
        end
        
        function variableChangedHandler(app, bankControl, event)
            bankIndex = find(bankControl == app.bankControls);
            app.updatePlots(bankIndex, event.Control.Row, NaN);
        end
        function filterChangedHandler(app, bankControl, event)        
            bankIndex = find(bankControl == app.bankControls);
            app.updatePlots(bankIndex, NaN, event.Control.Column);
        end
        function prePlotCommandsChangedHandler(app, bankControl, event)
            bankIndex = find(bankControl == app.bankControls);
            app.updatePlots(bankIndex, NaN, NaN);
        end
        
        function bankStruct = exportVisualization(app)
            for k = 1:app.getNumBanks()
                bankStruct(k) = app.bankControls(k).exportBank();
            end
        end
        
        function importVisualization(app, bankStruct)
            app.waitToUpdate = true;
            app.removeAllBanks();
            for k = 1:length(bankStruct)
                app.addBank(NaN, bankStruct(k));
            end
            app.waitToUpdate = false;
            app.updatePlots()
        end
        
        function summary = summarizeBankStruct(app, bankStruct)
            numBanks = length(bankStruct);
            summary = {};
            for k = 1:numBanks
                numFilters = length(bankStruct(k).Filters);
                numVariables = length(bankStruct(k).Variables);
                if isfield(bankStruct(k), 'PrePlotCommands') && ~isempty(bankStruct(k).PrePlotCommands)
                    prePlotCommands = bankStruct(k).PrePlotCommands;
                else
                    prePlotCommands = '(none)';
                end
                summary = [summary, ['Bank #', num2str(k)]];
                summary = [summary, indentText('Pre-plot commands:', 1)];
                summary = [summary, indentText(prePlotCommands, 2)];
                summary = [summary, indentText([num2str(numFilters), ' filters:'], 1)];
                for j = 1:numFilters
                    text = cell2mat(join(cellfun(@(s)indentText(strtrim(s), 2), bankStruct(k).Filters(j).Text, 'UniformOutput', false), newline));
                    if isempty(strtrim(text))
                        text = indentText('(empty)', 2);
                    end
                    summary = [summary, text];
                end
                summary = [summary, indentText([num2str(numVariables), ' variables:'], 1)];
                for j = 1:numVariables
                    text = cell2mat(join(cellfun(@(s)indentText(strtrim(s), 2), bankStruct(k).Variables(j).Text, 'UniformOutput', false), newline));
                    if isempty(strtrim(text))
                        text = indentText('(empty)', 2);
                    end
                    summary = [summary, text];
                end
            end
        end
        
        function updateSelectedVisualizationSummary(app)
            app.SelectedvisualizationTextArea.Value = app.summarizeBankStruct(app.getSelectedSavedVisualization());
        end
        
        function mergeItemsAndData(app, items1, itemsData1, items2, itemsData2)
            
        end
        
        function nextIndex = getNextSavedVisualizationsIndex(app)
            nextIndex = app.SavedvisualizationsListBox.UserData.nextIndex;
            app.SavedvisualizationsListBox.UserData.nextIndex = nextIndex + 1;
        end
        
        function savedVis = getSelectedSavedVisualization(app)
            savedVis = app.SavedvisualizationsListBox.Value;
            if ~isempty(savedVis)
                savedVis = savedVis{2};
            end
        end

        function idx = getSelectedSavedVisualizationID(app)
            savedVis = app.SavedvisualizationsListBox.Value;
            if ~isempty(savedVis)
                idx = savedVis{1};
            else
                idx = [];
            end
        end
        
        function exportAllBanksToFigure(app, varargin)
            f = figure();
            numBanks = app.getNumBanks();
            bankRows = zeros([1, numBanks]);
            bankCols = zeros([1, numBanks]);
            for k = 1:numBanks
                [nRows, nCols] = app.bankControls(k).getBankSize();
                bankRows(k) = nRows;
                bankCols(k) = nCols;
            end
            bankColOffsets = cumsum([0, bankCols]);
            maxRows = max(bankRows);
            totalCols = sum(bankCols);
            
            for k = 1:maxRows*totalCols
                disp(['************** k=', num2str(k)])
                overallCol = mod(k-1, totalCols)+1;
                bankIdx = find(bankColOffsets>=overallCol);
                bankIdx = bankIdx(1)-1;
                gridRow = floor((k-1)/totalCols)+1;
                gridCol = overallCol - bankColOffsets(bankIdx);
                disp(['bankIdx=', num2str(bankIdx), ' overallCol=', num2str(overallCol), ' gridRow=', num2str(gridRow), ' gridCol=', num2str(gridCol)])
                if gridRow <= bankRows(bankIdx)
                    disp('valid subplot')
                    % This is a valid subplot to copy:
                    ax = subtightplot(maxRows, totalCols, k, [0.075, 0.075], 0.05, 0.05, 'Parent', f);
                    app.bankControls(bankIdx).copyToNewAxes(ax, gridRow, gridCol);
                else
                    disp('invalid subplot')
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.createAxesContextMenu();
            drawnow;
            
            % This is to make sure convenience functions are included in
            % package as dependencies, even if they are only used in eval
            % strings.
            throwaway = @subIdx;
            throwaway = @coIdx;
            
            app.UIFigure.WindowState = 'maximized';
            
            app.maxPathDisplayLength = 80;
            
            app.waitToUpdate = false;

            app.SavedvisualizationsListBox.Items = {};
            app.SavedvisualizationsListBox.ItemsData = {};
            app.SavedvisualizationsListBox.UserData.nextIndex = 1;
            
            app.loadFieldNameDefinitions();
            app.RootdirectoryEditField.Value = '';
            app.RootdirectoryEditFieldValueChanged();

            app.addBank();
            app.addBank();
            
%             defaultVariables = ...
% {['subIdx(tip_x, 1, prot_ind),', newline,  ...
% 'subIdx(tip_y, 1, prot_ind),  ', newline,  ...
% 'subIdx(tip_z, 1, prot_ind),  ', newline,  ...
% 'subIdx(tip_x, prot_ind, ret_ind), ', newline,  ...
% 'subIdx(tip_y, prot_ind, ret_ind), ', newline,  ...
% 'subIdx(tip_z, prot_ind, ret_ind), ', newline,  ...
% 'subIdx(tip_x, ret_ind, NaN), ', newline,  ...
% 'subIdx(tip_y, ret_ind, NaN), ', newline,  ...
% 'subIdx(tip_z, ret_ind, NaN) '], ...
% ['subIdx(tip_y, 1, prot_ind), ', newline, ...
% 'subIdx(tip_z, 1, prot_ind), ', newline, ...
% 'subIdx(tip_y, prot_ind, ret_ind), ', newline, ...
% 'subIdx(tip_z, prot_ind, ret_ind), ', newline, ...
% 'subIdx(tip_y, ret_ind, NaN), ', newline, ...
% 'subIdx(tip_z, ret_ind, NaN) '], ...
% ['subIdx(tip_y, 1, prot_ind), ', newline, ...
% 'subIdx(tip_x, 1, prot_ind), ', newline, ...
% 'subIdx(tip_y, prot_ind, ret_ind), ', newline, ...
% 'subIdx(tip_x, prot_ind, ret_ind), ', newline, ...
% 'subIdx(tip_y, ret_ind, NaN), ', newline, ...
% 'subIdx(tip_x, ret_ind, NaN)']};
%             app.variableControls(1).Value = defaultVariables{1};
%             app.variableControls(2).Value = defaultVariables{2};
%             app.variableControls(3).Value = defaultVariables{3};
%             for k = 1:axesGridW
%                 app.filterControls(k).setFilterString(['k==', num2str(k)]);
%             end
            app.updatePlots()
            
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            
        end

        % Button pushed function: SelectrootdirectoryButton
        function SelectrootdirectoryButtonPushed(app, event)
            chosenDir = uigetdir('.', 'Choose a root directory in which to look for t_stats files');
            if ischar(chosenDir)
                app.RootdirectoryEditField.Value = chosenDir;
                app.populateTStats();
            end
        end

        % Value changed function: RootdirectoryEditField
        function RootdirectoryEditFieldValueChanged(app, event)
            value = app.RootdirectoryEditField.Value;
            app.populateTStats()
        end

        % Button pushed function: addAllTStats
        function addAllTStatsButtonPushed(app, event)
            pathsToAdd = app.filesFoundListBox.ItemsData;
            app.filesSelectedListBox.ItemsData = unique([pathsToAdd, app.filesSelectedListBox.ItemsData]);
            app.filesFoundListBox.ItemsData    = setdiff(app.filesFoundListBox.ItemsData, pathsToAdd);
            app.updateFileListBoxDisplays();
        end

        % Button pushed function: addTStats
        function addTStatsButtonPushed(app, event)
            pathsToAdd = app.filesFoundListBox.Value;
            app.filesSelectedListBox.ItemsData = unique([pathsToAdd, app.filesSelectedListBox.ItemsData]);
            app.filesFoundListBox.ItemsData = setdiff(app.filesFoundListBox.ItemsData, pathsToAdd);
            app.updateFileListBoxDisplays();
        end

        % Button pushed function: removeTStats
        function removeTStatsButtonPushed(app, event)
            app.filesSelectedListBox.ItemsData = setdiff(app.filesSelectedListBox.ItemsData, app.filesSelectedListBox.Value);
            app.populateTStats()
        end

        % Button pushed function: removeAllTStats
        function removeAllTStatsButtonPushed(app, event)
            app.filesSelectedListBox.ItemsData = {};
            app.filesSelectedListBox.Items = {};
            app.populateTStats()
        end

        % Button pushed function: Loadt_statsButton
        function Loadt_statsButtonPushed(app, event)
            app.startProgressDialog('Loading t_stats files...', 'Please wait')
            app.t_stats = table();
            for k = 1:length(app.filesSelectedListBox.ItemsData)
                app.progressDialog.Value = k/length(app.filesSelectedListBox.ItemsData);
                filename = app.filesSelectedListBox.ItemsData{k};
                loadedVar = load(filename, 't_stats');
                % Add a different ID for each 
                new_t_stats = struct2table(loadedVar.t_stats);
                new_t_stats.id = zeros([height(new_t_stats), 1]) + k;
                nameColumn = {};
                [nameColumn{1:height(new_t_stats), 1}] = deal(filename);
                new_t_stats.name = nameColumn;
                if isempty(app.t_stats)
                    app.t_stats = new_t_stats;
                else
                    oldFields = app.t_stats.Properties.VariableNames;
                    newFields = new_t_stats.Properties.VariableNames;
                    if length(newFields) ~= length(oldFields) || ~all(strcmp(newFields, oldFields))
                        inOldNotInNew = setdiff(oldFields, newFields);
                        inNewNotInOld = setdiff(newFields, oldFields);
                        for k = 1:length(inOldNotInNew)
                            field = inOldNotInNew{k};
                            fprintf('Warning, field ''%s'' is not found in t_stats file %s. Filling with NaN.\n', field, filename);
                            new_t_stats.(field) = NaN(height(new_t_stats), 1);
                        end
                        for k = 1:length(inNewNotInOld)
                            field = inNewNotInOld{k};
                            fprintf('Warning, field ''%s'' is found in t_stats file %s but not in others. Filling with NaN.\n', field, filename);
                            app.t_stats.(field) = NaN(height(app.t_stats), 1);
                        end
                    end
                    app.t_stats = [app.t_stats; new_t_stats];
                end
            end
            % Add index variable:
            app.t_stats.k = (1:height(app.t_stats))';
            close(app.progressDialog);
            app.updatePlots()
        end

        % Value changing function: SearchfieldsEditField
        function SearchfieldsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.populateFieldNameDefinitionsListBox(changingValue);
        end

        % Value changed function: FieldsListBox
        function FieldsListBoxValueChanged(app, event)
            fieldName = app.FieldsListBox.Value;
            app.updateFieldDetails(fieldName);
        end

        % Button pushed function: ViewthisvariableButton
        function ViewthisvariableButtonPushed(app, event)
            assignin('base', 'temporary_t_stats', app.t_stats)
            openVarCommand = ['openvar(''temporary_t_stats.', app.FieldsListBox.Value, ''')'];
            evalin('base', openVarCommand);
        end

        % Button pushed function: ViewallvariablesButton
        function ViewallvariablesButtonPushed(app, event)
            assignin('base', 'temporary_t_stats', app.t_stats)
            openVarCommand = 'openvar(''temporary_t_stats'')';
            evalin('base', openVarCommand);
        end

        % Button pushed function: 
        % SaveloadedvisualizationstofileButton
        function SaveloadedvisualizationstofileButtonPushed(app, event)
            allVisStructs = cellfun(@(x)x{2}, app.SavedvisualizationsListBox.ItemsData, 'UniformOutput', false);
            allVisNames = app.SavedvisualizationsListBox.Items;
            saveName = app.SavefileEditField.Value;
            if exist(saveName, 'file')
                selection = uiconfirm(app.UIFigure, ...
                    ['The chosen file ', saveName, ' already exists. Overwrite?'], ...
                    'Overwrite visualizations file?', ...
                    'Options', {'Overwrite', 'Cancel'},...
                    'Icon', 'warning');
                if strcmp(selection, 'Cancel')
                    return;
                end
            end
            save(saveName, 'allVisStructs', 'allVisNames');
        end

        % Callback function: ExportcurrentvisualizationButton, 
        % FielddetailsTextArea
        function ExportcurrentvisualizationButtonPushed(app, event)
            bankStruct = app.exportVisualization();
            exportName = app.NameforexportEditField.Value;
            if isempty(exportName)
                exportName = 'Untitled visualization';
            end
            nextIndex = app.getNextSavedVisualizationsIndex();
            L = length(app.SavedvisualizationsListBox.ItemsData);
            app.SavedvisualizationsListBox.Items{L+1} = exportName;
            app.SavedvisualizationsListBox.ItemsData{L+1} = {nextIndex, bankStruct};

        end

        % Value changed function: SavedvisualizationsListBox
        function SavedvisualizationsListBoxValueChanged(app, event)
%            value = app.getSelectedSavedVisualization();
            app.updateSelectedVisualizationSummary();
        end

        % Button pushed function: SetupselectedvisualizationButton
        function SetupselectedvisualizationButtonPushed(app, event)
            bankStruct = app.getSelectedSavedVisualization();
            app.importVisualization(bankStruct);
        end

        % Button pushed function: LoadvisualizationsfromfileButton
        function LoadvisualizationsfromfileButtonPushed(app, event)
            saveName = app.SavefileEditField.Value;
            s = load(saveName, 'allVisStructs', 'allVisNames');
            app.SavedvisualizationsListBox.ItemsData = [app.SavedvisualizationsListBox.ItemsData, cellfun(@(x){app.getNextSavedVisualizationsIndex(), x}, s.allVisStructs, 'UniformOutput', false)];
            app.SavedvisualizationsListBox.Items = [app.SavedvisualizationsListBox.Items, s.allVisNames];
        end

        % Button pushed function: BrowseSavedVisualizationsButton
        function BrowseSavedVisualizationsButtonPushed(app, event)
            chosenFile = uigetfile('.', 'Choose a file to load/save visualizations');
            if ischar(chosenFile)
                app.SavefileEditField.Value = chosenFile;
            end
        end

        % Button pushed function: DeleteselectedButton
        function DeleteselectedButtonPushed(app, event)
            selectedID = app.getSelectedSavedVisualizationID();
            if ~isempty(selectedID)
                idx = find(cellfun(@(el)(el{1} == selectedID), app.SavedvisualizationsListBox.ItemsData));
                app.SavedvisualizationsListBox.ItemsData(idx) = [];
                app.SavedvisualizationsListBox.Items(idx) = [];
            end
        end

        % Value changed function: tstatsFilterRegexEditField
        function tstatsFilterRegexEditFieldValueChanged(app, event)
            %value = app.tstatsFilterRegexEditField.Value;
            app.populateTStats();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectfilesTab = uitab(app.TabGroup);
            app.SelectfilesTab.Title = 'Select files';
            app.SelectrootdirectoryButton = uibutton(app.SelectfilesTab, 'push');
            app.SelectrootdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectrootdirectoryButtonPushed, true);
            app.SelectrootdirectoryButton.Text = 'Select root directory';
            app.RootdirectoryEditFieldLabel = uilabel(app.SelectfilesTab);
            app.RootdirectoryEditFieldLabel.Text = 'Root directory';
            app.RootdirectoryEditField = uieditfield(app.SelectfilesTab, 'text');
            app.RootdirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @RootdirectoryEditFieldValueChanged, true);
            app.recursiveSearchCheckBox = uicheckbox(app.SelectfilesTab);
            app.recursiveSearchCheckBox.Text = 'Look in subdirectories';
            app.recursiveSearchCheckBox.Value = true;
            app.t_statsfilesfoundLabel = uilabel(app.SelectfilesTab);
            app.t_statsfilesfoundLabel.Text = 't_stats files found';
            app.filesFoundListBox = uilistbox(app.SelectfilesTab);
            app.filesFoundListBox.Items = {};
            app.filesFoundListBox.Value = {};
            app.t_statsfilesselectedListBoxLabel = uilabel(app.SelectfilesTab);
            app.t_statsfilesselectedListBoxLabel.Text = 't_stats files selected';
            app.filesSelectedListBox = uilistbox(app.SelectfilesTab);
            app.filesSelectedListBox.Items = {};
            app.filesSelectedListBox.Value = {};
            app.addTStats = uibutton(app.SelectfilesTab, 'push');
            app.addTStats.ButtonPushedFcn = createCallbackFcn(app, @addTStatsButtonPushed, true);
            app.addTStats.Text = '>';
            app.removeTStats = uibutton(app.SelectfilesTab, 'push');
            app.removeTStats.ButtonPushedFcn = createCallbackFcn(app, @removeTStatsButtonPushed, true);
            app.removeTStats.Text = '<';
            app.moveTStatsUp = uibutton(app.SelectfilesTab, 'push');
            app.moveTStatsUp.Text = '/\';
            app.moveTStatsDown = uibutton(app.SelectfilesTab, 'push');
            app.moveTStatsDown.Text = '\/';
            app.rootDirectoryStatusLabel = uilabel(app.SelectfilesTab);
            app.rootDirectoryStatusLabel.Text = '';
            app.removeAllTStats = uibutton(app.SelectfilesTab, 'push');
            app.removeAllTStats.ButtonPushedFcn = createCallbackFcn(app, @removeAllTStatsButtonPushed, true);
            app.removeAllTStats.Text = '<<';
            app.addAllTStats = uibutton(app.SelectfilesTab, 'push');
            app.addAllTStats.ButtonPushedFcn = createCallbackFcn(app, @addAllTStatsButtonPushed, true);
            app.addAllTStats.Text = '>>';
            app.Loadt_statsButton = uibutton(app.SelectfilesTab, 'push');
            app.Loadt_statsButton.ButtonPushedFcn = createCallbackFcn(app, @Loadt_statsButtonPushed, true);
            app.Loadt_statsButton.Text = 'Load t_stats';
            app.FilterregexLabel = uilabel(app.SelectfilesTab);
            app.FilterregexLabel.Text = 'Filter regex:';
            app.tstatsFilterRegexEditField = uieditfield(app.SelectfilesTab, 'text');
            app.tstatsFilterRegexEditField.ValueChangedFcn = createCallbackFcn(app, @tstatsFilterRegexEditFieldValueChanged, true);
            app.tstatsFilterRegexEditField.Value = 't_stats\.mat';
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.Panel = uipanel(app.VisualizeTab);
            app.GridLayout = uigridlayout(app.Panel);
            app.VariableExplorerTab = uitab(app.TabGroup);
            app.VariableExplorerTab.Title = 'Variable Explorer';
            app.SearchfieldsEditFieldLabel = uilabel(app.VariableExplorerTab);
            app.SearchfieldsEditFieldLabel.Text = 'Search fields';
            app.SearchfieldsEditField = uieditfield(app.VariableExplorerTab, 'text');
            app.SearchfieldsEditField.ValueChangingFcn = createCallbackFcn(app, @SearchfieldsEditFieldValueChanging, true);
            app.FieldsListBoxLabel = uilabel(app.VariableExplorerTab);
            app.FieldsListBoxLabel.Text = 'Fields';
            app.FieldsListBox = uilistbox(app.VariableExplorerTab);
            app.FieldsListBox.Items = {};
            app.FieldsListBox.ValueChangedFcn = createCallbackFcn(app, @FieldsListBoxValueChanged, true);
            app.FieldsListBox.Value = {};
            app.FielddetailsTextAreaLabel = uilabel(app.VariableExplorerTab);
            app.FielddetailsTextAreaLabel.Text = 'Field details';
            app.FielddetailsTextArea = uitextarea(app.VariableExplorerTab);
            app.FielddetailsTextArea.ValueChangedFcn = createCallbackFcn(app, @ExportcurrentvisualizationButtonPushed, true);
            app.ViewthisvariableButton = uibutton(app.VariableExplorerTab, 'push');
            app.ViewthisvariableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewthisvariableButtonPushed, true);
            app.ViewthisvariableButton.Text = 'View this variable';
            app.ViewallvariablesButton = uibutton(app.VariableExplorerTab, 'push');
            app.ViewallvariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewallvariablesButtonPushed, true);
            app.ViewallvariablesButton.Text = 'View all variables';
            app.ConveniencefunctionsTextAreaLabel = uilabel(app.VariableExplorerTab);
            app.ConveniencefunctionsTextAreaLabel.Text = 'Convenience functions';
            app.ConveniencefunctionsTextArea = uitextarea(app.VariableExplorerTab);
            app.ConveniencefunctionsTextArea.Editable = 'off';
            app.ConveniencefunctionsTextArea.Value = {'To make plotting variables easier, several convenience functions have been made available:'; ''; '******** subIdx ************************************************'; 'Many of the t_stats variables contain a vector for each lick. To make it easier to slice these variables in parallel, the function "subIdx" is available. It can be used to slice using regular scalar values, or can slice using a variable has one scalar per lick.'; ''; 'Usage:'; '   newCellArray = subIdx(cellArray, indices)'; '   newCellArray = subIdx(cellArray, start, stop)'; '   newCellArray = subIdx(cellArray, start, step, stop)'; 'Use NaN in place of stop to indicate the end of the array.'; ''; 'Example:'; 'subIdx(tip_x, 1, prot_ind)'; 'For each lick, this would slice tip_x from index 1 to whatever value prot_ind takes for each lick.'; ''; '******** coIdx ************************************************'; 'When you specify only one variable to plot, it will be plotted as a histogram. If you instead want it to be plotted as a scatter plot vs its array of indices, you can use the coIdx function to conveniently do that.'; ''; 'Usage:'; '    indexArray = coIdx(cellArray)'; ''; 'Example:'; 'coIdx(magspeed_t), magspeed_t'; 'This would plot magspeed_t as a scatter plot with the indices of magspeed_t as the x-values.'};
            app.SavesTab = uitab(app.TabGroup);
            app.SavesTab.Title = 'Saves';
            app.SavefileEditFieldLabel = uilabel(app.SavesTab);
            app.SavefileEditFieldLabel.Text = 'Save file';
            app.SavefileEditField = uieditfield(app.SavesTab, 'text');
            app.SavedvisualizationsListBoxLabel = uilabel(app.SavesTab);
            app.SavedvisualizationsListBoxLabel.Text = {'Saved'; 'visualizations'};
            app.SavedvisualizationsListBox = uilistbox(app.SavesTab);
            app.SavedvisualizationsListBox.Items = {};
            app.SavedvisualizationsListBox.ValueChangedFcn = createCallbackFcn(app, @SavedvisualizationsListBoxValueChanged, true);
            app.SavedvisualizationsListBox.Value = {};
            app.LoadvisualizationsfromfileButton = uibutton(app.SavesTab, 'push');
            app.LoadvisualizationsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadvisualizationsfromfileButtonPushed, true);
            app.LoadvisualizationsfromfileButton.Text = {'Load visualizations  ↓'; 'from file                    ↓'};
            app.SetupselectedvisualizationButton = uibutton(app.SavesTab, 'push');
            app.SetupselectedvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @SetupselectedvisualizationButtonPushed, true);
            app.SetupselectedvisualizationButton.Text = {'Set up selected '; 'visualization'};
            app.SaveloadedvisualizationstofileButton = uibutton(app.SavesTab, 'push');
            app.SaveloadedvisualizationstofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveloadedvisualizationstofileButtonPushed, true);
            app.SaveloadedvisualizationstofileButton.Text = {'↑  Save loaded'; '↑  visualizations to file'};
            app.TextArea = uitextarea(app.SavesTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'A "visualization" is the set of all filter settings and variables loaded in the "Visualize" tab. You can save these "visualization" setups to a file, or load them from a file. To set up a saved visualization, select it in the box to the left and click "Set up selected visualization".'};
            app.ExportcurrentvisualizationButton = uibutton(app.SavesTab, 'push');
            app.ExportcurrentvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @ExportcurrentvisualizationButtonPushed, true);
            app.ExportcurrentvisualizationButton.Text = {'Export current'; 'visualization'};
            app.NameforexportEditFieldLabel = uilabel(app.SavesTab);
            app.NameforexportEditFieldLabel.Text = 'Name for export';
            app.NameforexportEditField = uieditfield(app.SavesTab, 'text');
            app.NameforexportEditField.Value = 'Untitled visualization';
            app.SelectedvisualizationTextAreaLabel = uilabel(app.SavesTab);
            app.SelectedvisualizationTextAreaLabel.Text = 'Selected visualization:';
            app.SelectedvisualizationTextArea = uitextarea(app.SavesTab);
            app.BrowseSavedVisualizationsButton = uibutton(app.SavesTab, 'push');
            app.BrowseSavedVisualizationsButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSavedVisualizationsButtonPushed, true);
            app.BrowseSavedVisualizationsButton.Text = 'Browse';
            app.DeleteselectedButton = uibutton(app.SavesTab, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedButtonPushed, true);
            app.DeleteselectedButton.Text = {'Delete'; 'selected'};
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HelpTextAreaLabel = uilabel(app.HelpTab);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.HelpTab);
            app.HelpTextArea.Value = {'How to use the tongue tip visualizer'; ''; 'Step 1. Select t_stats files'; '    (a) Go to the "Select files" tab'; '    (b) Select a root directory in which to look for t_stats files by either typing or copy/pasting a directory path '; '        or by clicking "Select Root Directory" and navigating to it.'; '    (c) If t_stats files are found, click to select one or more in the "t_stats files found" box. '; '        Note that if you select more than one t_stats files, the t_stats files will be concatenated as though they were a single file.'; '    (d) Click the ">" box to move selected files to the "t_stats files selected" box'; '    (e) Click "Load t_stats"'; ''; 'Step 2.  Plot variables'; '    (a) Edit the boxes along the left labeled "Variables to plot". '; '        Each variable box controls which variables or expressions are plotted in each axes in that box''s row.'; '        Enter one or more comma-separated list of variables to plot. '; '        Separate multiple sets of variables to plot with a newline.'; '    (b) Edit the boxes along the top labeled "Lick filter"'; '        Each filter box controls which licks are plotted in each axes in that box''s column. '; '        Enter one or more matlab expressions that evaluate to a boolean array. '; '        Separate multiple filters with a newline. '; '        Adding multiple filters will result in multiple overlayed graphs.'; '    (c) Edit the sampling controls along the top'; '        Each sampling control allows sub-sampling of the licks selected by each filter. '; '        For example, after filtering the licks, you might want to only plot 10 random examples. '; '        To do that, select "Random N" in the drop-down box, and input "10" in the box to the right of it.'; ''; 'FAQ/Troubleshooting:'; '    1. Why isn''t anything appearing in the axes?'; '        Make sure you remembered to click "Load t_stats" in the "Select Files" tab.'; '    2. How do I know what all the variables are called?'; '        The "Variable Explorer" tab allows you to browse, filter, and view all the available variables.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tongueTipVisualizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end